classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        GridLayout3               matlab.ui.container.GridLayout
        GridLayout8               matlab.ui.container.GridLayout
        GridLayout10              matlab.ui.container.GridLayout
        SaveDataButton            matlab.ui.control.Button
        LoadDataButton            matlab.ui.control.Button
        PostprocessDataButton     matlab.ui.control.Button
        GridLayout9               matlab.ui.container.GridLayout
        ViewDataDropDown          matlab.ui.control.DropDown
        ViewdataLabel             matlab.ui.control.Label
        AutoCaptureButton         matlab.ui.control.Button
        GridLayout5               matlab.ui.container.GridLayout
        GridLayout7               matlab.ui.container.GridLayout
        secLabel                  matlab.ui.control.Label
        CaptureDurationEditField  matlab.ui.control.NumericEditField
        TriggerPanel              matlab.ui.container.Panel
        GridLayout6               matlab.ui.container.GridLayout
        TrigValueSpinner          matlab.ui.control.Spinner
        ValueSpinnerLabel         matlab.ui.control.Label
        TrigSlopeDropDown         matlab.ui.control.DropDown
        SlopeDropDownLabel        matlab.ui.control.Label
        TrigChannelDropDown       matlab.ui.control.DropDown
        ChannelDropDownLabel      matlab.ui.control.Label
        ContinuousCaptureButton   matlab.ui.control.Button
        SingleCaptureButton       matlab.ui.control.Button
        GridLayout4               matlab.ui.container.GridLayout
        RelaySwitch               matlab.ui.control.ToggleSwitch
        GenerateSignalTextArea    matlab.ui.control.TextArea
        GenerateSignalButton      matlab.ui.control.Button
        GridLayout2               matlab.ui.container.GridLayout
        MeasureButton             matlab.ui.control.Button
        Label_ch4                 matlab.ui.control.Label
        Label_ch3                 matlab.ui.control.Label
        Label_ch2                 matlab.ui.control.Label
        Label_ch1                 matlab.ui.control.Label
        Label_src                 matlab.ui.control.Label
        tsecLabel                 matlab.ui.control.Label
        UIAxes_ch4                matlab.ui.control.UIAxes
        UIAxes_ch3                matlab.ui.control.UIAxes
        UIAxes_ch2                matlab.ui.control.UIAxes
        UIAxes_ch1                matlab.ui.control.UIAxes
        UIAxes_src                matlab.ui.control.UIAxes
        AutoCaptureContextMenu    matlab.ui.container.ContextMenu
    end

    
    properties (Access = private)
        SensorArrayPanel
        myhardware

        measure_modes = {"Pk-Pk" "Min" "Max" "Mean" "RMS" "Frequency"}
        measure_idxs = []

        auto_capture_sensor_gains
        auto_capture_gensig_mode
        auto_capture_data
    end

    methods (Access = private)

        function displayDataSlowYlim(app, t,src,ch1,ch2,ch3,ch4)
            a_src = ylim(app.UIAxes_src);
            a_ch1 = ylim(app.UIAxes_ch1);
            a_ch2 = ylim(app.UIAxes_ch2);
            a_ch3 = ylim(app.UIAxes_ch3);
            a_ch4 = ylim(app.UIAxes_ch4);

            displayData(app, t,src,ch1,ch2,ch3,ch4)

            b_src = ylim(app.UIAxes_src);
            b_ch1 = ylim(app.UIAxes_ch1);
            b_ch2 = ylim(app.UIAxes_ch2);
            b_ch3 = ylim(app.UIAxes_ch3);
            b_ch4 = ylim(app.UIAxes_ch4);

            if sum(abs(a_src - b_src)) / (a_src(2) - a_src(1)) < 0.5, ylim(app.UIAxes_src, a_src); end
            if sum(abs(a_ch1 - b_ch1)) / (a_ch1(2) - a_ch1(1)) < 0.5, ylim(app.UIAxes_ch1, a_ch1); end
            if sum(abs(a_ch2 - b_ch2)) / (a_ch2(2) - a_ch2(1)) < 0.5, ylim(app.UIAxes_ch2, a_ch2); end
            if sum(abs(a_ch3 - b_ch3)) / (a_ch3(2) - a_ch3(1)) < 0.5, ylim(app.UIAxes_ch3, a_ch3); end
            if sum(abs(a_ch4 - b_ch4)) / (a_ch4(2) - a_ch4(1)) < 0.5, ylim(app.UIAxes_ch4, a_ch4); end
        end

        function displayData(app, t,src,ch1,ch2,ch3,ch4)
            i = findTriggerStart(app, src,ch1,ch2,ch3,ch4);
            if isempty(i), i = 1; end

            t_ = t(i:end) - t(i);
            plot(app.UIAxes_src, t_, src(i:end))
            plot(app.UIAxes_ch1, t_, ch1(i:end))
            plot(app.UIAxes_ch2, t_, ch2(i:end))
            plot(app.UIAxes_ch3, t_, ch3(i:end))
            plot(app.UIAxes_ch4, t_, ch4(i:end))

            ylim(app.UIAxes_src, "auto")
            ylim(app.UIAxes_ch1, "auto")
            ylim(app.UIAxes_ch2, "auto")
            ylim(app.UIAxes_ch3, "auto")
            ylim(app.UIAxes_ch4, "auto")

            displayMeasurement(app, t,src,ch1,ch2,ch3,ch4);
        end

        function displayMeasurement(app, t,src,ch1,ch2,ch3,ch4)
            if nargin == 1
                t = []; src = []; ch1 = []; ch2 = []; ch3 = []; ch4 = [];
            end
            app.Label_src.Text = app.measure(t,src);
            app.Label_ch1.Text = app.measure(t,ch1);
            app.Label_ch2.Text = app.measure(t,ch2);
            app.Label_ch3.Text = app.measure(t,ch3);
            app.Label_ch4.Text = app.measure(t,ch4);
        end

        function text = measure(app, t,y)
            lines = {};
            for idx = app.measure_idxs
                mode = app.measure_modes{idx};
                if     strcmpi(mode, "Pk-Pk")
                    line = sprintf("%s: %f", mode, peak2peak(y));
                elseif strcmpi(mode, "min")
                    line = sprintf("%s: %f", mode, min(y));
                elseif strcmpi(mode, "max")
                    line = sprintf("%s: %f", mode, max(y));
                elseif strcmpi(mode, "mean")
                    if isempty(y), val = []; else val = mean(y); end
                    line = sprintf("%s: %f", mode, val);
                elseif strcmpi(mode, "RMS")
                    if isempty(y), val = []; else val = rms(y); end
                    line = sprintf("%s: %f", mode, val);
                elseif strcmpi(mode, "frequency")
                    mode = extractBetween(mode, 1,4);
                    if isempty(t)
                        line = sprintf("%s: ", mode);
                    else
                        fs = 1 / (t(2) - t(1));
                        line = sprintf("%s: %.2f Hz", mode, medfreq(y - mean(y), fs));
                    end
                end
                lines{end + 1} = line;
            end
            text = sprintf("%s\n", lines{:});
        end

        function start = findTriggerStart(app, src,ch1,ch2,ch3,ch4)
            channel = app.TrigChannelDropDown.Value;
            if     strcmpi(channel, 'Source'),    y = src;
            elseif strcmpi(channel, 'Channel 1'), y = ch1;
            elseif strcmpi(channel, 'Channel 2'), y = ch2;
            elseif strcmpi(channel, 'Channel 3'), y = ch3;
            elseif strcmpi(channel, 'Channel 4'), y = ch4;
            else, start = []; return;
            end

            triggerValue = app.TrigValueSpinner.Value;

            triggerSlope = lower(app.TrigSlopeDropDown.Value);
            start_candidate = [];
            if contains(triggerSlope, 'rising')
                mask = y >= triggerValue;
                mask(1) = 0;
                idx2 = find(mask);
                idx = idx2(y(idx2 - 1) < triggerValue);
                start_candidate = [start_candidate; idx(:)];
            end
            if contains(triggerSlope, 'falling')
                mask = y <= triggerValue;
                mask(1) = 0;
                idx2 = find(mask);
                idx = idx2(y(idx2 - 1) > triggerValue);
                start_candidate = [start_candidate; idx(:)];
            end
            start = min(start_candidate);
        end

        function port = askForSerialPort(app)
            port = '';
            devices = IDSerialComs();
            if ~isempty(devices)
                texts = {};
                for i = 1:size(devices,1)
                    texts{end+1} = sprintf("%s (COM%d)", devices{i,1}, devices{i,2});
                end
                [idx,tf] = ListDlg("ListString",texts, "SelectionMode","single", "ListSize",[300 100], ...
                                   "PromptString","Sensor board communication serial port");
                figure(app.UIFigure)
            else
                return
            end
            
            if tf
                port = sprintf("COM%d", devices{idx,2});
            end
        end

        function managePreferences(app, load_or_save)
            fields = {
                "app.CaptureDurationEditField.Value" 
                "app.TrigChannelDropDown.Value" 
                "app.TrigSlopeDropDown.Value" 
                "app.TrigValueSpinner.Value" 
                "app.GenerateSignalTextArea.Value" 
                "app.RelaySwitch.Value"
                "app.measure_idxs"
                "app.auto_capture_sensor_gains"
                "app.auto_capture_gensig_mode"};

            appdataDir = winqueryreg('HKEY_CURRENT_USER', 'Software\Microsoft\Windows\CurrentVersion\Explorer\Shell Folders', 'Local AppData');
            fname = fullfile(appdataDir, 'eddy-current-tomography-data-acquisition_preferences.mat');

            if     strcmpi(load_or_save, "load")
                if isfile(fname)
                    load(fname, 'preferences')
                    for i = 1:length(fields)
                        eval(sprintf("%s = preferences{%d};", fields{i}, i))
                    end
                end
            elseif strcmpi(load_or_save, "save")
                for i = 1:length(fields)
                    eval(sprintf("preferences{%d} = %s;", i, fields{i}))
                end
                save(fname, 'preferences')
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            d = uiprogressdlg(app.UIFigure, 'Title','Initializing', 'Indeterminate','on');
            
            app.managePreferences("load");

            TrigChannelDropDownValueChanged(app, []);
            CaptureDurationEditFieldValueChanged(app, []);
            app.displayMeasurement();
            app.LoadDataButton.Icon = fullfile(matlabroot,'toolbox','matlab','icons','file_open.png');
            app.SaveDataButton.Icon = fullfile(matlabroot,'toolbox','matlab','icons','file_save.png');
            app.PostprocessDataButton.Icon = fullfile(matlabroot,'toolbox','matlab','icons','help_fx.png');
            
            app.SensorArrayPanel = SensorArrayUI(app.GridLayout3);
            app.SensorArrayPanel.Layout.Row = 3;
            app.SensorArrayPanel.Layout.Column = 1;
            app.SensorArrayPanel.Enable = "off";
            
            port = app.askForSerialPort();
            try
                if isempty(port)
                    app.myhardware = MyHardware();
                    app.SensorArrayPanel.Tooltip = "Serial port not connected";
                else
                    app.myhardware = MyHardware(port);
                    app.SensorArrayPanel.myhardware = app.myhardware;
                    app.SensorArrayPanel.Enable = "on";
                    app.AutoCaptureButton.Enable = "on";
                end

                RelaySwitchValueChanged(app, []);

                app.ContinuousCaptureButton.Enable = "on";
                app.SingleCaptureButton.Enable = "on";
                app.RelaySwitch.Enable = "on";
                app.GenerateSignalButton.Enable = "on";

                app.ContinuousCaptureButton.Tooltip = sprintf("%d samples/channel/sec", app.myhardware.AnalogInputRate);
                app.SingleCaptureButton.Tooltip     = sprintf("%d samples/channel/sec", app.myhardware.AnalogInputRate);
                app.GenerateSignalButton.Tooltip    = sprintf("%d samples/sec",         app.myhardware.AnalogOutputRate);
            catch err
                f = errordlg(err.message);
                uiwait(f)
                figure(app.UIFigure)
                return
            end

            close(d)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.managePreferences("save");
            delete(app.myhardware)
            delete(app)    
        end

        % Button pushed function: ContinuousCaptureButton
        function ContinuousCaptureButtonPushed(app, event)
            if strcmpi(app.ContinuousCaptureButton.Text, "Run")
                span = seconds(app.CaptureDurationEditField.Value);
                app.myhardware.AnalogInputContinuous(span, @app.displayDataSlowYlim);
                app.ContinuousCaptureButton.Text = "Stop";
            else
                app.myhardware.AnalogInputStop();
                app.ContinuousCaptureButton.Text = "Run";
            end
        end

        % Button pushed function: SingleCaptureButton
        function SingleCaptureButtonPushed(app, event)
            app.myhardware.AnalogInputStop();
            app.ContinuousCaptureButton.Text = "Run";

            span = seconds(app.CaptureDurationEditField.Value);            
            [t,src,ch1,ch2,ch3,ch4] = app.myhardware.AnalogInputSingle(span);
            app.displayData(t,src,ch1,ch2,ch3,ch4)
        end

        % Button pushed function: GenerateSignalButton
        function GenerateSignalButtonPushed(app, event)
            if strcmpi(app.GenerateSignalButton.Text, "Generate signal")
                expr = sprintf('%s\n', app.GenerateSignalTextArea.Value{:});
                try
                    app.myhardware.AnalogOutputContinuous(expr);
                catch err
                    f = errordlg(err.message);
                    uiwait(f)
                    figure(app.UIFigure)
                    return
                end
                app.GenerateSignalTextArea.Enable = "off";
                app.GenerateSignalButton.Text = "Stop signal";
            else
                app.myhardware.AnalogOutputStop();
                app.GenerateSignalTextArea.Enable = "on";
                app.GenerateSignalButton.Text = "Generate signal";
            end
        end

        % Value changed function: TrigChannelDropDown
        function TrigChannelDropDownValueChanged(app, event)
            value = app.TrigChannelDropDown.Value;
            if strcmpi(value, 'none')
                app.TrigSlopeDropDown.Enable = false;
                app.TrigValueSpinner.Enable = false;
            else
                app.TrigSlopeDropDown.Enable = true;
                app.TrigValueSpinner.Enable = true;
            end
        end

        % Value changed function: RelaySwitch
        function RelaySwitchValueChanged(app, event)
            value = app.RelaySwitch.Value;
            bool = ~strcmp(value, app.RelaySwitch.Items{1});
            app.myhardware.SwitchRelay(bool);
        end

        % Button pushed function: MeasureButton
        function MeasureButtonPushed(app, event)
            [idx,tf] = ListDlg("ListString", app.measure_modes, ...
                               "InitialValue", app.measure_idxs, ...
                               "Name","Measure", "ListSize",[200 200]);
            figure(app.UIFigure)
            if ~tf, return; end

            if ~(length(app.measure_idxs) == length(idx) && all(app.measure_idxs == idx))
                app.measure_idxs = idx;
                app.displayMeasurement();
            end
        end

        % Value changed function: CaptureDurationEditField
        function CaptureDurationEditFieldValueChanged(app, event)
            lim = [0 app.CaptureDurationEditField.Value];
            xlim(app.UIAxes_src, lim)
            xlim(app.UIAxes_ch1, lim)
            xlim(app.UIAxes_ch2, lim)
            xlim(app.UIAxes_ch3, lim)
            xlim(app.UIAxes_ch4, lim)

            app.UIAxes_src.XAxis.Exponent = 0;
            app.UIAxes_ch1.XAxis.Exponent = 0;
            app.UIAxes_ch2.XAxis.Exponent = 0;
            app.UIAxes_ch3.XAxis.Exponent = 0;
            app.UIAxes_ch4.XAxis.Exponent = 0;
        end

        % Button pushed function: AutoCaptureButton
        function AutoCaptureButtonPushed(app, event)
            d = uiprogressdlg(app.UIFigure, 'Title','Acquiring data', 'Cancelable','on');

            if ~strcmpi(app.ContinuousCaptureButton.Text, "Run")
                ContinuousCaptureButtonPushed(app, []); % stop continuous capture
            end
            if ~strcmpi(app.GenerateSignalButton.Text, "Generate signal")
                GenerateSignalButtonPushed(app, []); % stop generaing signal
            end

            if app.auto_capture_gensig_mode == 2
                GenerateSignalButtonPushed(app, []); % start generaing signal
            end

            data = {};
            for i = 1:16
                if d.CancelRequested
                    break
                end

                gain1 = app.auto_capture_sensor_gains(16 * 0 + i);
                gain2 = app.auto_capture_sensor_gains(16 * 1 + i);
                gain3 = app.auto_capture_sensor_gains(16 * 2 + i);
                gain4 = app.auto_capture_sensor_gains(16 * 3 + i);

                timer = tic;
                confirmation = app.myhardware.SetAllSensorAndGain(i, gain1, i, gain2, i, gain3, i, gain4);

                if isempty(confirmation)
                    f = errordlg("Sensor board communication serial port is unresponsive");
                    uiwait(f)
                    break
                elseif ~confirmation
                    f = errordlg("Sensor board communication serial port has incorrect response");
                    uiwait(f)
                    break
                end

                app.SensorArrayPanel.HighlightSensorAndGain(1, i, gain1);
                app.SensorArrayPanel.HighlightSensorAndGain(2, i, gain2);
                app.SensorArrayPanel.HighlightSensorAndGain(3, i, gain3);
                app.SensorArrayPanel.HighlightSensorAndGain(4, i, gain4);

                d.Message = sprintf("Reading sensor %d...", i);
                d.Value = i / 16;

                span = seconds(app.CaptureDurationEditField.Value);
                if app.auto_capture_gensig_mode == 3
                    GenerateSignalButtonPushed(app, []); % start generaing signal
                end
                while toc(timer) < 0.01; end % wait for at least 10 ms after switching analog mux
                [t,src,ch1,ch2,ch3,ch4] = app.myhardware.AnalogInputSingle(span);
                if app.auto_capture_gensig_mode == 3
                    GenerateSignalButtonPushed(app, []); % stop generaing signal
                end

                app.displayData(t,src,ch1,ch2,ch3,ch4)
                data{i} = struct('sensor',i, 't',t, 'src',src, ...
                                 'ch1',ch1, 'ch1_gain',gain1,  ...
                                 'ch2',ch2, 'ch2_gain',gain2,  ...
                                 'ch3',ch3, 'ch3_gain',gain3,  ...
                                 'ch4',ch4, 'ch4_gain',gain4);
            end

            if app.auto_capture_gensig_mode == 2
                GenerateSignalButtonPushed(app, []); % stop generaing signal
            end

            close(d)

            if length(data) ~= 16
                return
            end

            app.auto_capture_data = data;
            app.ViewDataDropDown.Tooltip = sprintf('Data acquired at %s', datetime('now', 'Format','hh:mm:ss'));
            app.ViewdataLabel.Enable = "on";
            app.ViewDataDropDown.Enable = "on";
            app.SaveDataButton.Enable = "on";
            app.PostprocessDataButton.Enable = "on";
        end

        % Context menu opening function: AutoCaptureContextMenu
        function AutoCaptureContextMenuOpening(app, event)
            [sensor_gains, gensig_mode, tf] = AutoCaptureConfigDlg(app.auto_capture_sensor_gains, app.auto_capture_gensig_mode);
            if tf
                app.auto_capture_sensor_gains = sensor_gains; 
                app.auto_capture_gensig_mode  = gensig_mode;
            end
            figure(app.UIFigure)
        end

        % Value changed function: ViewDataDropDown
        function ViewDataDropDownValueChanged(app, event)
            value = app.ViewDataDropDown.Value;
            value = regexp(value, ".*?(\d+)", 'tokens', 'once');
            idx = str2num(value{1});

            f = app.auto_capture_data{idx};
            app.displayData(f.t,f.src,f.ch1,f.ch2,f.ch3,f.ch4)
        end

        % Button pushed function: PostprocessDataButton
        function PostprocessDataButtonPushed(app, event)
            operations = PostprocessData("operations");
            [idx,tf] = ListDlg("ListString",operations, "SelectionMode","single", ...
                               "Name","Postprocess data", "ListSize",[250 150]);
            figure(app.UIFigure)
            if ~tf, return; end

            PostprocessData(app.auto_capture_data, "Operation",operations{idx})
            figure(app.UIFigure)
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            [file, path] = uigetfile('*.mat', 'Load data');
            figure(app.UIFigure)
            if file == 0, return; end
            fname = fullfile(path, file);
            load(fname, 'data')

            if exist('data','var') && isa(data,'cell') && length(data) == 16
                app.auto_capture_data = data;
                app.ViewDataDropDown.Tooltip = sprintf('Data loaded from file "%s"',fname);
                app.ViewdataLabel.Enable = "on";
                app.ViewDataDropDown.Enable = "on";
                app.SaveDataButton.Enable = "on";
                app.PostprocessDataButton.Enable = "on";
                ViewDataDropDownValueChanged(app, [])
            else
                f = errordlg("Invalid data format");
                uiwait(f)
                figure(app.UIFigure)
            end
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            [file, path] = uiputfile('*.mat', 'Save data');
            figure(app.UIFigure)
            if file == 0, return; end
            fname = fullfile(path, file);
            data = app.auto_capture_data;
            save(fname, 'data')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Eddy current tomography data acquisition';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.UIAxes_src = uiaxes(app.GridLayout2);
            ylabel(app.UIAxes_src, 'Source', 'Interpreter', 'latex')
            app.UIAxes_ch1 = uiaxes(app.GridLayout2);
            ylabel(app.UIAxes_ch1, 'Channel 1', 'Interpreter', 'latex')
            app.UIAxes_ch2 = uiaxes(app.GridLayout2);
            ylabel(app.UIAxes_ch2, 'Channel 2', 'Interpreter', 'latex')
            app.UIAxes_ch3 = uiaxes(app.GridLayout2);
            ylabel(app.UIAxes_ch3, 'Channel 3', 'Interpreter', 'latex')
            app.UIAxes_ch4 = uiaxes(app.GridLayout2);
            ylabel(app.UIAxes_ch4, 'Channel 4', 'Interpreter', 'latex')
            app.tsecLabel = uilabel(app.GridLayout2);
            app.tsecLabel.Interpreter = 'latex';
            app.tsecLabel.Text = '$t$ (sec)';
            app.Label_src = uilabel(app.GridLayout2);
            app.Label_src.Text = '';
            app.Label_ch1 = uilabel(app.GridLayout2);
            app.Label_ch1.Text = '';
            app.Label_ch2 = uilabel(app.GridLayout2);
            app.Label_ch2.Text = '';
            app.Label_ch3 = uilabel(app.GridLayout2);
            app.Label_ch3.Text = '';
            app.Label_ch4 = uilabel(app.GridLayout2);
            app.Label_ch4.Text = '';
            app.MeasureButton = uibutton(app.GridLayout2, 'push');
            app.MeasureButton.ButtonPushedFcn = createCallbackFcn(app, @MeasureButtonPushed, true);
            app.MeasureButton.Tooltip = {'Select measurements'};
            app.MeasureButton.Text = 'Measure';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.GenerateSignalButton = uibutton(app.GridLayout4, 'push');
            app.GenerateSignalButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateSignalButtonPushed, true);
            app.GenerateSignalButton.Enable = 'off';
            app.GenerateSignalButton.Text = 'Generate signal';
            app.GenerateSignalTextArea = uitextarea(app.GridLayout4);
            app.GenerateSignalTextArea.Tooltip = {'Waveform of the generated signal.'; '"t" is a vector of time instants'; '"y" is the output vector of the same length'};
            app.GenerateSignalTextArea.Value = {'y = sin(2*pi*1000*t)'};
            app.RelaySwitch = uiswitch(app.GridLayout4, 'toggle');
            app.RelaySwitch.Items = {'Coil 1', 'Coil 2'};
            app.RelaySwitch.ValueChangedFcn = createCallbackFcn(app, @RelaySwitchValueChanged, true);
            app.RelaySwitch.Enable = 'off';
            app.RelaySwitch.Value = 'Coil 1';
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.SingleCaptureButton = uibutton(app.GridLayout5, 'push');
            app.SingleCaptureButton.ButtonPushedFcn = createCallbackFcn(app, @SingleCaptureButtonPushed, true);
            app.SingleCaptureButton.Enable = 'off';
            app.SingleCaptureButton.Text = 'Single';
            app.ContinuousCaptureButton = uibutton(app.GridLayout5, 'push');
            app.ContinuousCaptureButton.ButtonPushedFcn = createCallbackFcn(app, @ContinuousCaptureButtonPushed, true);
            app.ContinuousCaptureButton.Enable = 'off';
            app.ContinuousCaptureButton.Text = 'Run';
            app.TriggerPanel = uipanel(app.GridLayout5);
            app.TriggerPanel.Title = 'Trigger';
            app.GridLayout6 = uigridlayout(app.TriggerPanel);
            app.ChannelDropDownLabel = uilabel(app.GridLayout6);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.TrigChannelDropDown = uidropdown(app.GridLayout6);
            app.TrigChannelDropDown.Items = {'Source', 'Channel 1', 'Channel 2', 'Channel 3', 'Channel 4', 'None'};
            app.TrigChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @TrigChannelDropDownValueChanged, true);
            app.TrigChannelDropDown.Value = 'Source';
            app.SlopeDropDownLabel = uilabel(app.GridLayout6);
            app.SlopeDropDownLabel.Text = 'Slope';
            app.TrigSlopeDropDown = uidropdown(app.GridLayout6);
            app.TrigSlopeDropDown.Items = {'Rising', 'Falling', 'Rising or Falling'};
            app.TrigSlopeDropDown.Value = 'Rising';
            app.ValueSpinnerLabel = uilabel(app.GridLayout6);
            app.ValueSpinnerLabel.Text = 'Value';
            app.TrigValueSpinner = uispinner(app.GridLayout6);
            app.TrigValueSpinner.Limits = [-10 10];
            app.GridLayout7 = uigridlayout(app.GridLayout5);
            app.GridLayout7.Tooltip = {'Duration per acquisition'};
            app.CaptureDurationEditField = uieditfield(app.GridLayout7, 'numeric');
            app.CaptureDurationEditField.Limits = [1e-05 100];
            app.CaptureDurationEditField.ValueChangedFcn = createCallbackFcn(app, @CaptureDurationEditFieldValueChanged, true);
            app.CaptureDurationEditField.Value = 0.01;
            app.secLabel = uilabel(app.GridLayout7);
            app.secLabel.Text = 'sec';
            app.GridLayout8 = uigridlayout(app.GridLayout3);
            app.AutoCaptureButton = uibutton(app.GridLayout8, 'push');
            app.AutoCaptureButton.ButtonPushedFcn = createCallbackFcn(app, @AutoCaptureButtonPushed, true);
            app.AutoCaptureButton.Enable = 'off';
            app.AutoCaptureButton.Tooltip = {'Right click to configure'};
            app.AutoCaptureButton.Text = 'Acquire data from all sensors';
            app.GridLayout9 = uigridlayout(app.GridLayout8);
            app.ViewdataLabel = uilabel(app.GridLayout9);
            app.ViewdataLabel.Enable = 'off';
            app.ViewdataLabel.Text = 'View data';
            app.ViewDataDropDown = uidropdown(app.GridLayout9);
            app.ViewDataDropDown.Items = {'Sensor 1', 'Sensor 2', 'Sensor 3', 'Sensor 4', 'Sensor 5', 'Sensor 6', 'Sensor 7', 'Sensor 8', 'Sensor 9', 'Sensor 10', 'Sensor 11', 'Sensor 12', 'Sensor 13', 'Sensor 14', 'Sensor 15', 'Sensor 16'};
            app.ViewDataDropDown.ValueChangedFcn = createCallbackFcn(app, @ViewDataDropDownValueChanged, true);
            app.ViewDataDropDown.Enable = 'off';
            app.ViewDataDropDown.Value = 'Sensor 16';
            app.GridLayout10 = uigridlayout(app.GridLayout8);
            app.PostprocessDataButton = uibutton(app.GridLayout10, 'push');
            app.PostprocessDataButton.ButtonPushedFcn = createCallbackFcn(app, @PostprocessDataButtonPushed, true);
            app.PostprocessDataButton.Enable = 'off';
            app.PostprocessDataButton.Tooltip = {'Postprocess data'};
            app.PostprocessDataButton.Text = '';
            app.LoadDataButton = uibutton(app.GridLayout10, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Tooltip = {'Load data'};
            app.LoadDataButton.Text = '';
            app.SaveDataButton = uibutton(app.GridLayout10, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Enable = 'off';
            app.SaveDataButton.Tooltip = {'Save data'};
            app.SaveDataButton.Text = '';
            app.AutoCaptureContextMenu = uicontextmenu(app.UIFigure);
            app.AutoCaptureContextMenu.ContextMenuOpeningFcn = createCallbackFcn(app, @AutoCaptureContextMenuOpening, true);
            % Assign app.AutoCaptureContextMenu
            app.AutoCaptureButton.ContextMenu = app.AutoCaptureContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end